classdef appfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        hEditField              matlab.ui.control.EditField
        vEditField              matlab.ui.control.EditField
        AceptarButton_2         matlab.ui.control.Button
        ESCtiempoLabel          matlab.ui.control.Label
        ESCamplitudLabel        matlab.ui.control.Label
        DesplazamientoLabel     matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        EditField2              matlab.ui.control.NumericEditField
        EditField3              matlab.ui.control.NumericEditField
        cEditField              matlab.ui.control.NumericEditField
        IngresacLabel           matlab.ui.control.Label
        FuncionesDropDown       matlab.ui.control.DropDown
        FuncionesDropDownLabel  matlab.ui.control.Label
        aEditField              matlab.ui.control.NumericEditField
        ingresabLabel           matlab.ui.control.Label
        ingresaaLabel_2         matlab.ui.control.Label
        wEditField              matlab.ui.control.NumericEditField
        AceptarButton           matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            value = app.FuncionesDropDown.Value;
            
            
            if strcmp(value,"Sen")
               
              w = app.wEditField.Value;
              wa=4*pi/w;
              a = app.aEditField.Value;
              t= -wa:1/(10*w):wa;
              
              y=a*sin(w*t);
              app.UIAxes.XLim = [-wa,wa];
              app.UIAxes.YLim = [-a-2,a+2];
              plot(app.UIAxes,t,y)
              
            elseif strcmp(value,"Lineal")
                m= app.wEditField.Value;
                b= app.aEditField.Value;
                t= -abs(-b/m)-5:1:5+abs(b/m);
                y= m*t+b;
                if b~=0
                    if m>0
                    app.UIAxes.XLim = [(-b/m)-4,(b/m)+4];
                    
                    elseif m< 0
                    app.UIAxes.XLim = [(b/m)-5,(-b/m)+5];
                    end
                    app.UIAxes.YLim = [abs(b)-10,abs(b)+10];
                end
              

                plot(app.UIAxes,t,y)

            elseif strcmp(value,"Triangular")
              w=app.wEditField.Value;
              a=app.aEditField.Value;
              t = -5/w:1/(40*w):5/w;
              y = a*sawtooth(2*pi*w*t);
            
               app.UIAxes.XLim = [-5/w,5/w];
              app.UIAxes.YLim = [-a-2,a+2];
              plot(app.UIAxes,t,y)
            elseif strcmp(value,"Exponencial")
                t = -2:1/20:2;
                a = app.wEditField.Value;
                b = app.aEditField.Value;
                %z=ones(1,81);
                %A=z.*a;
                l = exp(-b*t);
                y = l.*a;
              app.UIAxes.XLim = [-2,2];
              app.UIAxes.YLim = [0,a+5];
                plot(app.UIAxes,t,y)
            elseif strcmp(value,"Cuadrada")
                w=app.wEditField.Value;
                a=app.aEditField.Value;
                t = -5/w:1/(20*w):5/w;
                y = a*square(2*pi*w*t);
                app.UIAxes.XLim = [-5/w,5/w];
                app.UIAxes.YLim = [-a-2,a+2];
                 plot(app.UIAxes,t,y)

             elseif strcmp(value,"Cuadrática")
                a=app.wEditField.Value;
                b=app.aEditField.Value;
                c= app.cEditField.Value;
                t = -2-abs(b):1/(20):2+abs(b);
                
                y = a*(t.^2)+ t.*b+c;
                y0= min(y);
                app.UIAxes.YLim = [y0-2,y0+15];
                app.UIAxes.XLim = [-2-abs(b),2+abs(b)];    
                plot(app.UIAxes,t,y)
                
            elseif strcmp(value,"Pulso")
                w=app.wEditField.Value;
                a=app.aEditField.Value;
                t= -w:0.0001:w;
                y=zeros(size(t));
                y(t<w/2 & t>-w/2)=a;
                plot(app.UIAxes,t,y)
                app.UIAxes.YLim = [0,a+2];
                 app.UIAxes.XLim = [-w-2,w+2];  
            elseif strcmp(value,"Secuencia de impulso")
                h0= app.hEditField.Value;
                h = str2num(h0);
                h1 = sort(h);
                hf = h1(end);
                hi= h1(1);
                if (hi>=0)
                    hi=0;
                end
                v2 = app.vEditField.Value;
                v1 = str2num(v2);
                v = sort (v1);
                l = length(v);
                i1 = v(1);
                il = v(end);
                in = i1-1;
                s = il+1;
                t = in:1:s;
                k=1;
                y=zeros(size(t));
                while (k<=l)
                y(t==v1(k))=h(k);
                k = k+1;
                end
                app.UIAxes.XLim = [in,s];  
                app.UIAxes.YLim = [hi,hf+2];
                stem(app.UIAxes,t,y);
                

            end
        end

        % Value changed function: FuncionesDropDown
        function FuncionesDropDownValueChanged(app, event)
            value = app.FuncionesDropDown.Value;
            global t y 

                if strcmp(value,"Secuencia de impulso") 
                    app.vEditField.Position=[212,231,100,22];
                    app.hEditField.Position = [212,198,100,22];
                    app.wEditField.Position = [8,8,100,22];
                    app.aEditField.Position =[123,8,100,22];
                    app.vEditField.Visible="on";
                    app.hEditField.Visible="on";
                    app.wEditField.Visible="off";
                    app.aEditField.Visible="off";
                    app.ingresaaLabel_2.Position = [44,231,152,25];
                    app.ingresabLabel.Position = [44,198,152,22];

                    app.IngresacLabel.Visible="off";    
                    app.cEditField.Visible="off";
                    app.ingresaaLabel_2.Text =" Ingresa vector de impulsos";
                    app.ingresabLabel.Text=" Ingresa vector de amplitud";
                else  
                    app.wEditField.Position = [212,231,100,22];
                    app.aEditField.Position =[212,198,100,22];
                    app.vEditField.Position =[8,8,100,22];
                    app.hEditField.Position =[123,8,100,22];
                    app.vEditField.Visible="off";
                    app.hEditField.Visible="off";
                    app.wEditField.Visible="on";
                    app.aEditField.Visible="on";
                    app.ingresaaLabel_2.Position = [92,231,104,25];
                    app.ingresabLabel.Position = [92,198,104,22];
                     if strcmp(value,"Pulso")
                        app.IngresacLabel.Visible="off";    
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa w";
                        app.ingresabLabel.Text=" Ingresa a";
                    elseif strcmp(value,"Sen")
                        app.IngresacLabel.Visible="off";    
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa w";
                        app.ingresabLabel.Text=" Ingresa a";
                       
                        
                    elseif strcmp(value,"Cuadrática")
                        app.IngresacLabel.Visible="on";    
                        app.cEditField.Visible="on";
                        app.ingresaaLabel_2.Text="Ingresa a";
                        app.ingresabLabel.Text="Ingresa b";
                        app.IngresacLabel.Text="Ingresa c";
                        
                    elseif strcmp(value,"Lineal")
                        app.IngresacLabel.Visible="off"; 
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa m";
                        app.ingresabLabel.Text=" Ingresa b";
                         
                     elseif strcmp(value,"Triangular")
                        app.IngresacLabel.Visible="off"; 
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa w";
                        app.ingresabLabel.Text=" Ingresa a";

                    elseif strcmp(value,"Cuadrada")
                        app.IngresacLabel.Visible="off";  
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa w";
                        app.ingresabLabel.Text=" Ingresa a";
                        
                     elseif strcmp(value,"Exponencial")
                        app.IngresacLabel.Visible="off";  
                        app.cEditField.Visible="off";
                        app.ingresaaLabel_2.Text =" Ingresa a";
                        app.ingresabLabel.Text=" Ingresa b";   
                        
                     end
                end
        end

        % Button pushed function: AceptarButton_2
        function AceptarButton_2Pushed(app, event)
            value = app.FuncionesDropDown.Value;
            
            
            if strcmp(value,"Sen")
              
              w = app.wEditField.Value;
              wa=4*pi/w;
              a = app.aEditField.Value;
              t= -wa:1/(10*w):wa;
              
              y=a*sin(w*t);
              y1=y;
              %plot(app.UIAxes2,t,y)
              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
              t1=t;
              t2=t-x;
              delt=t2-t;
              t1=t+delt;
             tesc=t1.*esc;
             tesc2=tesc-t1;
              tfps=t1+tesc2;
              if esc>0
                  if tfps(1)<t(1)
                       thz1=tfps(1);
                  else 
                      thz1 = t(1);
                  end
    
                  if tfps(end)>t(end)
                       thz2=tfps(end);
                  else 
                      thz2 = t(end);
                  end
              elseif esc<0
                  if tfps(end)<t(1)
                       thz1=tfps(end);
                  else 
                      thz1 = t(1);
                  end
    
                  if tfps(1)>t(end)
                       thz2=tfps(1);
                  else 
                      thz2 = t(1);
                  end
              end


%               if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [(-wa-x)*esc,wa];   
%                  else
%                 app.UIAxes2.XLim = [-wa,(wa+x)*esc]; 
%                  end
%               elseif (0<esc)&&(esc<1)
%                 if x==0
%                   app.UIAxes2.XLim = [(-wa)*esc,wa*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(-wa-x)*esc,wa]; 
%                 else
%                   app.UIAxes2.XLim = [-wa,wa]; 
%                 end
%                 
%               elseif esc<0
%                   if x >= 0
%                 app.UIAxes2.XLim = [(wa-x)*esc,wa];   
%                  else
%                 app.UIAxes2.XLim = [-wa,(wa+x)*esc]; 
%                  end
%               end
              y2=a2*y;
              app.UIAxes2.YLim = [-(a2*a)-2,(a2*a)+2];
%               if esc1>=-1
%               app.UIAxes2.XLim = [thz1,thz2];
%               else
%                   app.UIAxes2.XLim = [thz1,thz2];
%               end
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  while y2-y1~=0
                    y1=y+y3*0.1*i;
                    i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end

             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")

             %app.UIAxes2.YLim = [-(a2*a)-2,(a2*a)+2];


            elseif strcmp(value,"Lineal")
                m= app.wEditField.Value;
                b= app.aEditField.Value;
                t= -abs(-b/m)-5:1:abs(-b/m)+5;
                y= m*t+b;
                y1=y;
              %plot(app.UIAxes2,t,y)
              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%               if esc>=1 
%                  if x >= 0  
%                      if b~=0
%                         if m>0
%                         app.UIAxes2.XLim = [((-b/m)-5-x)*esc,(b/m)+5];
%                         
%                         elseif m< 0
%                         app.UIAxes2.XLim = [((b/m)-5-x)*esc,(-b/m)+5];
%                         end
% 
%                      end
%                        %app.UIAxes2.XLim = [(-abs(-b/m)-5-x)*esc,abs(-b/m)+5];   
%                  else
%                      if b~=0
%                         if m>0
%                         app.UIAxes2.XLim = [(-b/m)-5,((b/m)+5+x)*esc];
%                         
%                         elseif m< 0
%                         app.UIAxes2.XLim = [((b/m)-5),((-b/m)+5+x)*esc];
%                         end
% 
%                      end
%                          %app.UIAxes2.XLim = [-abs(-b/m)-5,(abs(-b/m)+5+x)*esc]; 
%                  end
%               else
%                  if x==0
%                      if b~=0
%                         if m>0
%                         app.UIAxes2.XLim = [((-b/m)-5)*esc,((b/m)+5)*esc];
%                         
%                         elseif m< 0
%                         app.UIAxes2.XLim = [((b/m)-5)*esc,((-b/m)+5+x)*esc];
%                         end
% 
%                      end
%                   
%                      
%                      %app.UIAxes2.XLim = [(-abs(-b/m)-5)*esc,(abs(-b/m)+5)*esc]; 
% 
%                 elseif x>0
%                     if b~=0
%                         if m>0
%                         app.UIAxes2.XLim = [((-b/m)-5-x)*esc,(b/m)+5];
%                         
%                         elseif m< 0
%                         app.UIAxes2.XLim = [((b/m)-5-x)*esc,(-b/m)+5];
%                         end
% 
%                      end
% 
%                   %app.UIAxes2.XLim = [(-abs(-b/m)-5-x)*esc,abs(-b/m)+5]; 
% 
%                  else
% 
%                      if b~=0
%                         if m>0
%                         app.UIAxes.XLim = [(-b/m)-4,(b/m)+4];
%                         
%                         elseif m< 0
%                         app.UIAxes.XLim = [(b/m)-5,(-b/m)+5];
%                         end
%                     end
%                  % app.UIAxes2.XLim = [-abs(-b/m)-5,abs(-b/m)+5]; 
% 
%                 end
%               end
               y2=a2*y;
              app.UIAxes2.YLim = [-(abs(b)*a2)-10,(abs(b)*a2)+10];
               
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  for i =1:10
                    y1=y+y3*0.1*i;
                    %i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")

            elseif strcmp(value,"Triangular")
              w=app.wEditField.Value;
              a=app.aEditField.Value;
              t = -5/w:1/(40*w):5/w;
              y = a*sawtooth(2*pi*w*t);
              y1 = y;

              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%               if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [((-5/w)-x)*esc,abs(5/w)];   
%                  else
%                 app.UIAxes2.XLim = [-5/w,((5/w)+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(-5/w)*esc,(5/w)*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(-(5/w)-x)*esc,5/w]; 
%                 else
%                   app.UIAxes2.XLim = [-5/w,5/w]; 
%                 end
%               end
              y2=a2*y;
              app.UIAxes2.YLim = [-(a2*a)-2,(a2*a)+2];
               
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  for i =1:10
                    y1=y+y3*0.1*i;
                    %i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")

            elseif strcmp(value,"Exponencial")
                t = -2:1/20:2;
                a = app.wEditField.Value;
                b = app.aEditField.Value;
                %z=ones(1,81);
                %A=z.*A;
                l = exp(-b*t);
                y = l.*a;
                y1=y;

              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%               if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [(-2-x)*esc,2];   
%                  else
%                 app.UIAxes2.XLim = [-2,((2)+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(-2)*esc,(2)*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(-(2)-x)*esc,2]; 
%                 else
%                   app.UIAxes2.XLim = [-2,2]; 
%                 end
%               end

              y2=a2*y;
              app.UIAxes2.YLim = [0,(a2*a)+5];
               
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  while y2-y1~=0
                    y1=y+y3*0.1*i;
                    i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")
                
            elseif strcmp(value,"Cuadrada")
                w=app.wEditField.Value;
                a=app.aEditField.Value;
                t = -5/w:1/(20*w):5/w;
                y = a*square(2*pi*w*t);
              y1 = y;

              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%               if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [((-5/w)-x)*esc,abs(5/w)];   
%                  else
%                 app.UIAxes2.XLim = [-5/w,((5/w)+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(-5/w)*esc,(5/w)*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(-(5/w)-x)*esc,5/w]; 
%                 else
%                   app.UIAxes2.XLim = [-5/w,5/w]; 
%                 end
%               end

              y2=a2*y;
              app.UIAxes2.YLim = [-(a2*a)-2,(a2*a)+2];
               
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  while y2-y1~=0
                    y1=y+y3*0.1*i;
                    i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")
        
                
             elseif strcmp(value,"Cuadrática")
                a=app.wEditField.Value;
                b=app.aEditField.Value;
                c= app.cEditField.Value;
                t = -2-abs(b):1/(20):2+abs(b);
                 
                y = a*(t.^2)+ t.*b+c;
                y0= min(y);
               y1 = y;

              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%               if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [((-2-abs(b))-x)*esc,(2+abs(b))];   
%                  else
%                 app.UIAxes2.XLim = [-2-abs(b),((2+abs(b))+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(-2-abs(b))*esc,(2+abs(b))*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [((-2-abs(b))-x)*esc,2+abs(b)]; 
%                 else
%                   app.UIAxes2.XLim = [-2-abs(b),2+abs(b)]; 
%                 end
%               end
              y2=a2*y;
              app.UIAxes2.YLim = [(y0*a)-2,(y0*a)+15];
               
              plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  for i =1:10
                    y1=y+y3*0.1*i;
                    %i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y2)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")

            
             elseif strcmp(value,"Pulso")
                w=app.wEditField.Value;
                a=app.aEditField.Value;
                t= -w:0.0001:w;
                y=zeros(size(t));
                y(t<w/2 & t>-w/2)=a;

                %y1(t>w/2 & t<-w/2)=0;
                %y1=y(t<w/2 & t>-w/2);
                y1 = y;
              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%                 if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [(-w-x)*esc,w];   
%                  else
%                 app.UIAxes2.XLim = [-w,(w+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(-w)*esc,w*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(-w-x)*esc,w]; 
%                 else
%                   app.UIAxes2.XLim = [-w,w]; 
%                 end
%               end
          
                y2=a2*y;
               app.UIAxes2.YLim = [0,(a2*a)+2];
               plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  for i = 1:10
                    y1=y+y3*0.1*i;
                    %i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=plot(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=plot(app.UIAxes2,t,y1)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= plot(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=plot(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                  end
             
             plot(app.UIAxes2,tfps,y2)
             hold (app.UIAxes2,"on")
             plot(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")               

               
            elseif strcmp(value,"Secuencia de impulso")
                h0= app.hEditField.Value;
                h = str2num(h0);
                h1 = sort(h);
                hf = h1(end);
                hi= h1(1);
                if (hi>=0)
                    hi=0;
                end
                v2 = app.vEditField.Value;
                v1 = str2num(v2);
                v = sort (v1);
                l = length(v);
                i1 = v(1);
                il = v(end);
                in = i1-1;
                s = il+1;
                t = in:1:s;
                k=1;
                y=zeros(size(t));
                while (k<=l)
                y(t==v1(k))=h(k);
                k = k+1;
                end

                y1 = y;
              a2=app.EditField.Value;
              x=app.EditField2.Value;
              esc1=app.EditField3.Value;
              esc= 1/esc1;
%                 if esc>=1 
%                  if x >= 0
%                 app.UIAxes2.XLim = [(in-x)*esc,s];   
%                  else
%                 app.UIAxes2.XLim = [in,(s+x)*esc]; 
%                  end
%               else
%                 if x==0
%                   app.UIAxes2.XLim = [(in)*esc,s*esc];   
%                 elseif x>0
%                   app.UIAxes2.XLim = [(in-x)*esc,s]; 
%                 else
%                   app.UIAxes2.XLim = [in,s]; 
%                 end
%               end
          
                y2=a2*y;
               app.UIAxes2.YLim = [hi,(a2*hf)+2];
               stem(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
              hold (app.UIAxes2,"on")
              y3=y2-y;
              i=0;
             
                  for i = 1:10
                    y1=y+y3*0.1*i;
                    %i=i+1;
                    %hold (app.UIAxes2,"off")
                    j=stem(app.UIAxes2,t,y1);
                       
                     %hold (app.UIAxes2,"off")
                    pause(0.5);
                    delete(j);
                  end
              p4=stem(app.UIAxes2,t,y1)
              delete(p4);
            
              t1=t;
              t2=t-x;
              delt=t2-t;
              i=0;
               while t2-t1~=0
                    t1=t+delt*0.1*i;
                    i=i+1;
                   b= stem(app.UIAxes2,t1,y2)
                   pause(0.5);
                   delete(b);
                  
                end
             %esc=app.EditField3.Value;
             tesc=t1.*esc;
             tesc2=tesc-t1;
                 for i = 1:10 
                        tfps=t1+tesc2*0.1*i;
                        n=stem(app.UIAxes2,tfps,y2)
                        pause(0.5);
                        delete(n);
                 end
            thz= zeros(size(t));
            ln=length(tfps);
             for i= 1:ln
             if round(tfps(i)) == tfps(i)
               yf(i)=y2(i);
               if round(y2(i))== y2(i)
                 yf(i)=y2(i);
             else
                 yf(i)=0;
             end
             else
                 yf(i)=0;
             end
             

                end
             stem(app.UIAxes2,tfps,yf)
             hold (app.UIAxes2,"on")  
             stem(app.UIAxes2,t,y,"color",'red',"LineStyle","--")
             hold (app.UIAxes2,"off")   


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Señal Transformada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 1];
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.wEditField = uieditfield(app.UIFigure, 'numeric');
            app.wEditField.Value = 1;
            app.ingresaaLabel_2 = uilabel(app.UIFigure);
            app.ingresaaLabel_2.Text = 'ingresa a';
            app.ingresabLabel = uilabel(app.UIFigure);
            app.ingresabLabel.Text = 'ingresa b';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 1;
            app.FuncionesDropDownLabel = uilabel(app.UIFigure);
            app.FuncionesDropDownLabel.Text = 'Funciones';
            app.FuncionesDropDown = uidropdown(app.UIFigure);
            app.FuncionesDropDown.Items = {'Cuadrática', 'Pulso', 'Sen', 'Exponencial', 'Lineal', 'Triangular', 'Cuadrada', 'Secuencia de impulso'};
            app.FuncionesDropDown.ValueChangedFcn = createCallbackFcn(app, @FuncionesDropDownValueChanged, true);
            app.FuncionesDropDown.Value = 'Cuadrática';
            app.IngresacLabel = uilabel(app.UIFigure);
            app.IngresacLabel.Text = 'Ingresa c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.Value = 1;
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Value = 1;
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.DesplazamientoLabel = uilabel(app.UIFigure);
            app.DesplazamientoLabel.Text = 'Desplazamiento';
            app.ESCamplitudLabel = uilabel(app.UIFigure);
            app.ESCamplitudLabel.Text = 'ESC amplitud';
            app.ESCtiempoLabel = uilabel(app.UIFigure);
            app.ESCtiempoLabel.Text = 'ESC tiempo';
            app.AceptarButton_2 = uibutton(app.UIFigure, 'push');
            app.AceptarButton_2.ButtonPushedFcn = createCallbackFcn(app, @AceptarButton_2Pushed, true);
            app.AceptarButton_2.Text = 'Aceptar';
            app.vEditField = uieditfield(app.UIFigure, 'text');
            app.vEditField.Visible = 'off';
            app.hEditField = uieditfield(app.UIFigure, 'text');
            app.hEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end